classdef forward_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        foward_window   matlab.ui.Figure            % UI Figure
        LabelEditField  matlab.ui.control.Label     % a
        aEditField      matlab.ui.control.EditField
        LabelEditField2 matlab.ui.control.Label     % alpha
        alphaEditField  matlab.ui.control.EditField
        LabelEditField3 matlab.ui.control.Label     % d
        dEditField      matlab.ui.control.EditField
        LabelEditField4 matlab.ui.control.Label     % joint angles
        jaEditField     matlab.ui.control.EditField
        calculate       matlab.ui.control.Button    % calculate
        LabelTextArea   matlab.ui.control.Label     % End Effector T
        output          matlab.ui.control.TextArea 
        Label           matlab.ui.control.Label     % Forward Kinematics Ca...
    end

    
    properties (Access = public)
        a double
        alpha double
        d double
        joint_angles double
        T double
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % aEditField value changed function
        function aEditFieldValueChanged(app)
            a_strarray= strsplit(app.aEditField.Value);
            app.a = str2double(a_strarray);
        end

        % calculate button pushed function
        function calculateButtonPushed(app)
            n = length(app.a);
            if length(app.alpha) ~= n || length(app.a) ~= n || length(app.d) ~= n || length(app.joint_angles) ~= n              
                errordlg('Input dimensions do not match','Error');
                return;
            end

            % Initialize transformation matrix= I
            app.T = eye(4);
            % Calculate forward kinematics
            for i = 1:n
                % Compute transformation matrix for the current link
                A_i = [cos(app.joint_angles(i)), -sin(app.joint_angles(i))*cos(app.alpha(i)), sin(app.joint_angles(i))*sin(app.alpha(i)), app.a(i)*cos(app.joint_angles(i));
                    sin(app.joint_angles(i)), cos(app.joint_angles(i))*cos(app.alpha(i)), -cos(app.joint_angles(i))*sin(app.alpha(i)), app.a(i)*sin(app.joint_angles(i));
                    0, sin(app.alpha(i)), cos(app.alpha(i)), app.d(i);
                    0, 0, 0, 1];
                
                % Update overall transformation matrix
                app.T = app.T * A_i;
            end
            end_effector_display = sprintf('%0.2f\t%0.2f\t%0.2f\t%0.2f\n%0.2f\t%0.2f\t%0.2f\t%0.2f\n%0.2f\t%0.2f\t%0.2f\t%0.2f\n%0.2f\t%0.2f\t%0.2f\t%0.2f', app.T.');  
            app.output.Value= end_effector_display;
        end

        % alphaEditField value changed function
        function alphaEditFieldValueChanged(app)
            alpha_strarray= strsplit(app.alphaEditField.Value);
            alpha_deg = str2double(alpha_strarray);
            app.alpha = deg2rad(alpha_deg);
        end

        % dEditField value changed function
        function dEditFieldValueChanged(app)
            d_strarray= strsplit(app.dEditField.Value);
            app.d = str2double(d_strarray);
            
        end

        % jaEditField value changed function
        function jaEditFieldValueChanged(app)
            ja_strarray= strsplit(app.jaEditField.Value);
            ja_deg = str2double(ja_strarray);
            app.joint_angles = deg2rad(ja_deg);
            
        end

        % output value changed function
        function outputValueChanged(app)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.foward_window = uifigure;
            app.foward_window.Name = 'UI Figure';
            setAutoResize(app, app.foward_window, true)
            app.LabelEditField = uilabel(app.foward_window);
            app.LabelEditField.Text = 'a';
            app.aEditField = uieditfield(app.foward_window, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged);
            app.LabelEditField2 = uilabel(app.foward_window);
            app.LabelEditField2.Text = 'alpha';
            app.alphaEditField = uieditfield(app.foward_window, 'text');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged);
            app.LabelEditField3 = uilabel(app.foward_window);
            app.LabelEditField3.Text = 'd';
            app.dEditField = uieditfield(app.foward_window, 'text');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged);
            app.LabelEditField4 = uilabel(app.foward_window);
            app.LabelEditField4.Text = 'joint angles';
            app.jaEditField = uieditfield(app.foward_window, 'text');
            app.jaEditField.ValueChangedFcn = createCallbackFcn(app, @jaEditFieldValueChanged);
            app.calculate = uibutton(app.foward_window, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed);
            app.calculate.Text = 'calculate';
            app.LabelTextArea = uilabel(app.foward_window);
            app.LabelTextArea.Text = 'End Effector T';
            app.output = uitextarea(app.foward_window);
            app.output.ValueChangedFcn = createCallbackFcn(app, @outputValueChanged);
            app.output.Editable = 'off';
            app.Label = uilabel(app.foward_window);
            app.Label.Text = 'Forward Kinematics Calculator';
        end
    end

    methods (Access = public)

        % Construct app
        function app = forward_window()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.foward_window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.foward_window)
        end
    end
end