classdef inverse_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure            % UI Figure
        Label                  matlab.ui.control.Label     % Inverse Kinema...
        LabelEditField         matlab.ui.control.Label     % a
        aEditField             matlab.ui.control.EditField
        LabelEditField2        matlab.ui.control.Label     % d
        dEditField             matlab.ui.control.EditField
        LabelEditField3        matlab.ui.control.Label     % alpha
        alphaEditField         matlab.ui.control.EditField
        LabelEditField4        matlab.ui.control.Label     % Theta_initial
        theta_initialEditField matlab.ui.control.EditField
        LabelEditField5        matlab.ui.control.Label     % EE position
        EEEditField            matlab.ui.control.EditField
        Button                 matlab.ui.control.Button    % Calculate
        LabelTextArea          matlab.ui.control.Label     % Joint Angles
        output                 matlab.ui.control.TextArea 
    end

    
    properties (Access = public)
        
        a double
        alpha double
        d double
        ee_position double
        theta_initial double
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % aEditField value changed function
        function aEditFieldValueChanged(app)
            a_strarray= strsplit(app.aEditField.Value)
            app.a = str2double(a_strarray)
            
        end

        % alphaEditField value changed function
        function alphaEditFieldValueChanged(app)
            alpha_strarray= strsplit(app.alphaEditField.Value);
            alpha_deg = str2double(alpha_strarray)
            app.alpha = deg2rad(alpha_deg)
            
        end

        % dEditField value changed function
        function dEditFieldValueChanged(app)
            d_strarray= strsplit(app.dEditField.Value)
            app.d = str2double(d_strarray)
        end

        % theta_initialEditField value changed function
        function theta_initialEditFieldValueChanged(app)
            theta_initial_strarray= strsplit(app.theta_initialEditField.Value);
            theta_initial_deg = str2double(theta_initial_strarray);
            app.theta_initial = deg2rad(theta_initial_deg)
            
        end

        % EEEditField value changed function
        function EEEditFieldValueChanged(app)
            ee_position_strarray= strsplit(app.EEEditField.Value)
            app.ee_position = str2double(ee_position_strarray)
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            n = length(app.a);
            if length(app.alpha) ~= n || length(app.a) ~= n || length(app.d) ~= n || length(app.theta_initial) ~= n  || length(app.ee_position) ~= 3
                errordlg('Input dimensions do not match or more than 3 arguments were given to position field','Error');
                return;
            end
            app.theta_initial = app.theta_initial';
            % Positions desired
            x_desired = app.ee_position(1);
            y_desired= app.ee_position(2);
            z_desired= app.ee_position(3);
            
            % Perform inverse kinematics calculation
            % Convergence criteria and initialization
            max_iter = 1000;
            epsilon = 1e-6;
            % Initialize the loop variables
            iter = 0;
            delta_theta = inf(3, 1);
            
            % Main loop for IK
            while norm(delta_theta) > epsilon && iter < max_iter
                % Forward Kinematics
                T = eye(4);
                for i = 1:n
                    T_i = [cos(app.theta_initial(i)), -sin(app.theta_initial(i)) * cos(app.alpha(i)), sin(app.theta_initial(i)) * sin(app.alpha(i)), app.a(i) * cos(app.theta_initial(i));
                        sin(app.theta_initial(i)), cos(app.theta_initial(i)) * cos(app.alpha(i)), -cos(app.theta_initial(i)) * sin(app.alpha(i)), app.a(i) * sin(app.theta_initial(i));
                        0, sin(app.alpha(i)), cos(app.alpha(i)), app.d(i);
                        0, 0, 0, 1];
                    T = T * T_i;
                end
                
                % Current end-effector position
                x_current = T(1, 4);
                y_current = T(2, 4);
                z_current = T(3, 4);
                
                % Calculate the position error
                error_x = x_desired - x_current;
                error_y = y_desired - y_current;
                error_z = z_desired - z_current;
                error = [error_x; error_y; error_z];
                
                % Jacobian matrix
                J = zeros(3, n);
                for i = 1:n
                    % transformation matrix  to the i-th link
                    T_i = eye(4);
                    for j = 1:i-1
                        T_j = [cos(app.theta_initial(j)), -sin(app.theta_initial(j))*cos(app.alpha(j)), sin(app.theta_initial(j))*sin(app.alpha(j)), app.a(j)*cos(app.theta_initial(j));
                            sin(app.theta_initial(j)), cos(app.theta_initial(j))*cos(app.alpha(j)), -cos(app.theta_initial(j))*sin(app.alpha(j)), app.a(j)*sin(app.theta_initial(j));
                            0, sin(app.alpha(j)), cos(app.alpha(j)), app.d(j);
                            0, 0, 0, 1];
                        T_i = T_i * T_j;
                    end
                    % Compute the previous z-axis direction in the current frame
                    z_prev = T_i(1:3, 3);
                    % Compute the Jacobian column corresponding to joint i
                    J(:, i) = cross(z_prev, T(1:3, 4) - T_i(1:3, 4));
                end
                
                % Pseudo-inverse
                J_pseudo_inv = pinv(J);
                
                %change in joint angles
                delta_theta = J_pseudo_inv * error;
                
                % Update joint angles
                app.theta_initial = app.theta_initial + delta_theta;
                
                iter = iter + 1;
            end
            
            % Display the final joint angles
            %fprintf('Joint angles after %d iterations:\n', iter);
            disp(app.theta_initial);
            if norm(delta_theta) <= epsilon
                jointangles_display = sprintf('Converged in %d iterations.\nJoint Angles in rad: [%.2f, %.2f, %.2f]', iter, app.theta_initial(1), app.theta_initial(2), app.theta_initial(3));
                app.output.Value= jointangles_display;
            else
                app.output.Value= 'Failed to converge within the maximum iterations.';
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Inverse Kinematics Calculator';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged);
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'text');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged);
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'text');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged);
            app.LabelEditField4 = uilabel(app.UIFigure);
            app.LabelEditField4.Text = 'Theta_initial';
            app.theta_initialEditField = uieditfield(app.UIFigure, 'text');
            app.theta_initialEditField.ValueChangedFcn = createCallbackFcn(app, @theta_initialEditFieldValueChanged);
            app.LabelEditField5 = uilabel(app.UIFigure);
            app.LabelEditField5.Text = 'EE position';
            app.EEEditField = uieditfield(app.UIFigure, 'text');
            app.EEEditField.ValueChangedFcn = createCallbackFcn(app, @EEEditFieldValueChanged);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Calculate';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Joint Angles';
            app.output = uitextarea(app.UIFigure);
            app.output.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = inverse_window()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end