classdef trialgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Program       matlab.ui.Figure           % UI Figure
        LabelDropDown matlab.ui.control.Label    % Options
        DropDown      matlab.ui.control.DropDown % None, forward kinematics...
        Label         matlab.ui.control.Label    % Choose your desired program
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDown.Value='None';
        end

        % DropDown value changed function
        function DropDownValueChanged(app, event)
            option = app.DropDown.Value;
            switch option
                case 'forward kinematics' % Option value depends on how you set up your dropdown items
                    run('forward_window.mlapp');
                case 'inverse kinematics'
                    run('inverse_window.mlapp');
                case 'workspace'
                    run('workspace_window.mlapp');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Program = uifigure;
            app.Program.Name = 'UI Figure';
            setAutoResize(app, app.Program, true)
            app.LabelDropDown = uilabel(app.Program);
            app.LabelDropDown.Text = 'Options';
            app.DropDown = uidropdown(app.Program);
            app.DropDown.Items = {'None', 'forward kinematics', 'inverse kinematics', 'workspace'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'None';
            app.Label = uilabel(app.Program);
            app.Label.Text = 'Choose your desired program';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trialgui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Program)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Program)
        end
    end
end