classdef workspace_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure            % UI Figure
        Label              matlab.ui.control.Label     % Workspace Plot
        LabelEditField     matlab.ui.control.Label     % Alpha
        alphaEditField     matlab.ui.control.EditField
        LabelEditField2    matlab.ui.control.Label     % A
        aEditField         matlab.ui.control.EditField
        LabelEditField3    matlab.ui.control.Label     % D
        dEditField         matlab.ui.control.EditField
        LabelEditField4    matlab.ui.control.Label     % Max Theta
        theta_maxEditField matlab.ui.control.EditField
        calculate          matlab.ui.control.Button    % Calculate
        Axes               matlab.ui.control.UIAxes   
    end

    
    properties (Access = public)
        a double
        alpha double
        d double
        theta_max double
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Generate some random data
            x = rand(100, 1);
            y = rand(100, 1);
            z = rand(100, 1);

            % Plot the data in 3D
            plot3(app.Axes, x, y, z, 'o');
            xlabel(app.Axes, 'X');
            ylabel(app.Axes, 'Y');
            zlabel(app.Axes, 'Z');
            title(app.Axes, '3D Plot');
        end

        % alphaEditField value changed function
        function alphaEditFieldValueChanged(app)
            alpha_strarray= strsplit(app.alphaEditField.Value);
            alpha_deg = str2double(alpha_strarray);
            app.alpha = deg2rad(alpha_deg);
            
        end

        % aEditField value changed function
        function aEditFieldValueChanged(app)
            a_strarray= strsplit(app.aEditField.Value);
            app.a = str2double(a_strarray);
        end

        % dEditField value changed function
        function dEditFieldValueChanged(app)
            d_strarray= strsplit(app.dEditField.Value);
            app.d = str2double(d_strarray);
        end

        % theta_maxEditField value changed function
        function theta_maxEditFieldValueChanged(app)
            
            theta_max_strarray= strsplit(app.theta_maxEditField.Value);
            app.theta_max = str2double(theta_max_strarray);
            
        end

        % calculate button pushed function
        function calculateButtonPushed(app)
            % checking for error in dimensions
            n = length(app.a);
            if length(app.alpha) ~= n || length(app.a) ~= n || length(app.d) ~= n || length(app.theta_max) ~= n
                error('Input dimensions do not match');
                return;
            end
            % creating robot structure
            L = Link.empty(0, n);
            
            for i = 1:n
                L(i)= [ Link( [0, app.d(i), app.a(i), app.alpha(i)])];
            end
            robot = SerialLink(L);
            %creating random points to be plotted
            num_points = 3000;
            v = zeros(num_points, 3);
            for k = 1:num_points
                q = app.theta_max .* rand(1, n);
                T = robot.fkine(q);
                v(k, :) = transl(T);
            end
            % Set Z label
            view(app.Axes, 3);
            scatter3(app.Axes,v(:, 1), v(:, 2), v(:, 3), '^', 'MarkerFaceColor', 'b', 'MarkerEdgeColor', 'r', 'LineWidth', 0.8);
            grid on
            hold on
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Workspace Plot';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'text');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged);
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'A';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged);
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'D';
            app.dEditField = uieditfield(app.UIFigure, 'text');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged);
            app.LabelEditField4 = uilabel(app.UIFigure);
            app.LabelEditField4.Text = 'Max Theta';
            app.theta_maxEditField = uieditfield(app.UIFigure, 'text');
            app.theta_maxEditField.ValueChangedFcn = createCallbackFcn(app, @theta_maxEditFieldValueChanged);
            app.calculate = uibutton(app.UIFigure, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed);
            app.calculate.Text = 'Calculate';
            app.Axes = uiaxes(app.UIFigure);
            xlabel(app.Axes, 'X');
            ylabel(app.Axes, 'Y');
        end
    end

    methods (Access = public)

        % Construct app
        function app = workspace_window()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end